classdef GUIv2_ChannelSelectDetails < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_ax_polar                 matlab.ui.container.Panel
        check_advanced                 matlab.ui.control.CheckBox
        tgl_FilterSetUp                matlab.ui.control.StateButton
        AmpHiEditField                 matlab.ui.control.NumericEditField
        AmpHiEditFieldLabel            matlab.ui.control.Label
        AmpLoEditField                 matlab.ui.control.NumericEditField
        AmpLoEditFieldLabel            matlab.ui.control.Label
        PhaseHiEditField               matlab.ui.control.NumericEditField
        PhaseHiEditFieldLabel          matlab.ui.control.Label
        FreqsHzLabel                   matlab.ui.control.Label
        PhaseLoEditField               matlab.ui.control.NumericEditField
        PhaseLoEditFieldLabel          matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        ChannelSelectionDropDown       matlab.ui.control.DropDown
        ChannelSelectionDropDownLabel  matlab.ui.control.Label
        LayoutDropDown                 matlab.ui.control.DropDown
        LayoutDropDownLabel            matlab.ui.control.Label
        tgl_StartStop                  matlab.ui.control.StateButton
        DisconnectButton               matlab.ui.control.Button
        ConnecttoNSPButton             matlab.ui.control.Button
        compGUIv2_ElectrodeGrid        compGUIv2_ElectrodeGrid
        ax_spectrogram                 matlab.ui.control.UIAxes
        ax_spectrum                    matlab.ui.control.UIAxes
        ax_timesignals                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        DAQstatus logical = false; % is connected to data source?
        SignalSetUp logical = false; % show signal data trace?
        FilterSetUp logical = false; 
        selChannelInd = []; % index of channel to display details 
        allChannelIDs = []; % ID number of channels to display on grid
        channelIDlist = []; % list of all available channel IDs
        allChannelInfo = {}; % info data structure 
        channelList = {}; % channel names for on-screen display
        rawData = {}; % raw data structure 
        fltData = {}; % filtered data structure 
        bufferSizeGrid = 0; % samples 
        FilterObjs = {}; % filter coefficients and initial conditions
        fSamples % sample rate(s)
        time0 % time-on of DAQ system according to computer clock
        initTic % initial tic of app
        ax_polar % handle to polar axes in panel
    end
    
    properties (Access = private)
        HardwareFuncs = []; % structure of functions to interact with hardware 
        myTimer
        h_rawPlt
        h_fltPltPhase
        h_fltPltAmp
        h_spectrum
        h_spectrogram
        h_PACplt
    end
    
    methods (Access = private)
        
        function [newBuffer, newTail, newAll] = ...
                bufferjuggle(app, oldBuffer, oldTail, newData, bufferFunc)
            % helper function
            newBuffer = bufferFunc(oldBuffer, oldTail);
            newTail = newData;
            newAll = bufferFunc(newBuffer, newTail);
        end

        function updateDisplay(app, obj, evt)
            % =====================================================
            % ================= MAIN GUI FUNCTION =================
            % =====================================================
            tNow = datetime;

            try 

            % ensure connection with hardware 
            if ~app.DAQstatus
                stop(app.myTimer);
            end

            % get raw data (adapted from iterReadBrain) 
            rawIDs = app.allChannelIDs;
            rawData = app.rawData; chInfo = rawData(1,:);
            [newTails, tailNames, tailIDs] = ...
                app.HardwareFuncs.GetNewRawData(rawIDs);
            if width(rawData) == width(newTails)
                % assume same order 
                for CH = 1:width(rawData)
                    newTail = newTails{CH};
                    [rawData{2,CH}, rawData{3,CH}, rawData{4,CH}] = ...
                        bufferjuggle(app,rawData{2,CH},rawData{3,CH},newTail,@bufferData);
                end
            else
                % assign manually using channel ID
                for ch = 1:size(newTails,2)
                    newTail = newTails{ch};
                    tailName = tailNames{ch};
                    %CH = find(strcmp(tailName, rawNames)); 
                    tailID = tailIDs(ch);
                    CH = find(tailID == rawIDs);
                    if isempty(CH)
                        error(['Unrecognized new raw data label: ',tailName]);
                    end
                    if length(CH) > 1
                        error(['Raw data label ',tailName,' is not unique.']);
                    end
                    [rawData{2,CH}, rawData{3,CH}, rawData{4,CH}] = ...
                        bufferjuggle(app,rawData{2,CH},rawData{3,CH},newTail,@bufferData);
                end
            end
            app.rawData = rawData; 

            % filter all channels 
            if app.FilterSetUp
                for ch = 1:size(app.fltData,2)
                    newTail = app.rawData{3,ch}; 
                    tTail = newTail(:,1); newTail = newTail(:,2);
                    for bnd = 1:size(app.fltData,3)
                        [fltTail, fltCond] = filter(...
                            app.FilterObjs{1,ch,bnd}, ...
                            app.FilterObjs{2,ch,bnd}, ...
                            newTail, ...
                            app.FilterObjs{4,ch,bnd});
                        [app.fltData{2,ch,bnd}, app.fltData{3,ch,bnd}, app.fltData{4,ch,bnd}] = ...
                            bufferjuggle(app,app.fltData{2,ch,bnd}, app.fltData{3,ch,bnd}, ...
                            [tTail,fltTail], @bufferData);
                        app.FilterObjs{4,ch,bnd} = fltCond;
                    end
                end
            end

            % update electrode grid 
            if app.compGUIv2_ElectrodeGrid.showElecGrid
                try
                    if app.compGUIv2_ElectrodeGrid.useGridFunc2
                        datasend = app.fltData(4,:,:);
                        datasend = squeeze(datasend)';
                    else
                        datasend = rawData(4,:);
                    end
                    app.compGUIv2_ElectrodeGrid.updateGrid(...
                        app.channelIDlist, rawIDs, datasend, ...
                        app.bufferSizeGrid, app.fSamples);
                catch ME4
                    getReport(ME4)
                    uialert(app.UIFigure, ME4.message, 'Electrode Grid Issue')
                    app.compGUIv2_ElectrodeGrid.showElecGrid = false;
                end
            end

            % update raw data plot
            if app.SignalSetUp
                try
                chInd = app.selChannelInd; fSample = app.fSamples(chInd);
                if app.check_advanced.Value
                    rawPlt = data2timetable(rawData(4,chInd),rawData(1,chInd),app.time0);
                    rawPlt = rawPlt{1};
                    xRaw = rawPlt.Variables;
                    app.h_rawPlt.YData = xRaw;
                    tRaw = rawPlt.Time - tNow; tRaw = seconds(tRaw);
                    app.h_rawPlt.XData = tRaw;
                else
                    xRaw = rawData{4,chInd}(:,2);
                    app.h_rawPlt.YData = xRaw(2:end)';
                end

                % update filtered data plot
                if app.FilterSetUp
                try
                fltShiftPhase = app.FilterObjs{3,chInd,1};
                fltShiftAmp   = app.FilterObjs{3,chInd,2};
                fltShiftAmp = ceil(fltShiftAmp/2); fltShiftPhase = ceil(fltShiftPhase/2);
                if app.check_advanced.Value

                    % phase filter
                    fltPlt = data2timetable(...
                        app.fltData(4,chInd,1), app.fltData(1,chInd,1), app.time0);
                    fltPlt = fltPlt{1};
                    xFlt = fltPlt.Variables;
                    xFltPhase = xFlt(fltShiftPhase:end,:);
                    app.h_fltPltPhase.YData = xFltPhase; 
                    tFlt = fltPlt.Time - tNow; tFlt = seconds(tFlt);
                    tFlt = tFlt(1:(end-fltShiftPhase+1));
                    app.h_fltPltPhase.XData = tFlt;

                    % amplitude filter 
                    fltPlt = data2timetable(...
                        app.fltData(4,chInd,2), app.fltData(1,chInd,2), app.time0);
                    fltPlt = fltPlt{1}(fltShiftAmp:end,:);
                    xFlt = fltPlt.Variables;
                    xFltAmp = xFlt(fltShiftAmp:end,:);
                    app.h_fltPltAmp.YData = xFltAmp; 
                    tFlt = fltPlt.Time - tNow; tFlt = seconds(tFlt);
                    tFlt = tFlt(1:(end-fltShiftAmp+1));
                    app.h_fltPltAmp.XData = tFlt;

                else
                    xFltPhase = app.fltData{4,chInd,1}(:,2); 
                    xFltAmp   = app.fltData{4,chInd,2}(:,2);
                    xFltPhase = xFltPhase(fltShiftPhase:end); 
                    xFltAmp   = xFltAmp(fltShiftAmp:end); 
                    app.h_fltPltPhase.YData = xFltPhase(2:end)';
                    app.h_fltPltAmp.YData   = xFltAmp(2:end)';
                end

                try
                lenPACsig = min(length(xFltPhase), length(xFltAmp));
                [~,amp,phi] = ...
                    calcPAC(xFltPhase(1:lenPACsig), xFltAmp(1:lenPACsig), 18);
                app.h_PACplt.RData = [amp, amp(1)];
                %app.h_PACplt.YData = amp; 
                if app.check_advanced.Value
                    %app.h_PACplt.XData = phi;
                    app.h_PACplt.ThetaData = [phi, phi(1)];
                end
                catch ME4
                    app.FilterSetUp = false;
                    getReport(ME4)
                    uialert(app.UIFigure, ME4.message, 'issue updating PAC plot')
                end

                catch ME6
                    app.FilterSetUp = false;
                    getReport(ME6)
                    uialert(app.UIFigure, ME6.message, 'issue filtering data')
                end
                end

                % update spectrum plot
                [p,f] = periodogram(xRaw, [], [], fSample, 'power');
                app.h_spectrum.YData = p;
                if app.check_advanced.Value
                    app.h_spectrum.XData = f;
                end

                % update spectrogram plot
                [S,ff,tSpec] = spectrogram(xRaw, [], [], f, fSample);
                app.h_spectrogram.CData = abs(S);
                if app.check_advanced.Value
                    tSpec = tSpec + tRaw(1);
                    app.h_spectrogram.XData = tSpec;
                    app.h_spectrogram.YData = ff;
                end

                catch ME5
                    app.SignalSetUp = false;
                    getReport(ME5)
                    uialert(app.UIFigure, ME5.message, 'issue plotting data')
                end
            end

            catch ME
                if contains(ME.message, 'No continuous data')
                    % do nothing; wait for data
                else
                    getReport(ME)
                    stop(app.myTimer)
                    keyboard
                end
            end
        end
        
        function StartMainLoop(app, obj, evt)
            % ====================================================
            % =============== TIMER START FUNCTION ===============
            % ====================================================
            tNow = datetime;

            % (re-)init data structs
            app.HardwareFuncs.ShutdownRecording();
            try
                [rawH, rawT, rawB, rawN] = app.HardwareFuncs.InitRawData(...
                    app.allChannelIDs, app.bufferSizeGrid);
            catch ME1 
                warning(['Error on first attempt: ',ME1.message]);
                pause(1);
                [rawH, rawT, rawB, rawN] = app.HardwareFuncs.InitRawData(...
                    app.allChannelIDs, app.bufferSizeGrid);
            end
            app.rawData = [rawN; rawH; rawT; rawB];
            if app.FilterSetUp
                filtShiftPhase = [app.FilterObjs{3,:,1}];
                filtShiftAmp   = [app.FilterObjs{3,:,2}];
                filtShiftAmp = ceil(filtShiftAmp/2); filtShiftPhase = ceil(filtShiftPhase/2);
                fltDataPhase = [rawN; rawH; rawT; initFilteredData(rawB, filtShiftPhase)]; 
                fltDataAmp   = [rawN; rawH; rawT; initFilteredData(rawB, filtShiftAmp)]; 
                app.fltData = cat(3, fltDataPhase, fltDataAmp);
            end

            % init raw data plot
            chInd = app.selChannelInd; 
            if app.SignalSetUp
                try
                fSample = app.fSamples(chInd);
                app.ax_timesignals.Subtitle.String = rawN{chInd}.Name;
                app.ax_timesignals.YLabel.String = ['Amplitude (',rawN{chInd}.Unit,')'];
                app.ax_timesignals.XLabel.String = 'time (seconds)';
                rawPlt = data2timetable(rawB(chInd),rawN(chInd),app.time0); 
                rawPlt = rawPlt{1};
                tRaw = rawPlt.Time - tNow; xRaw = rawPlt.Variables;
                if ~app.check_advanced.Value
                    % do not track time exactly 
                    tRaw = (((-length(tRaw)+1):0)/fSample);
                else
                    tRaw = seconds(tRaw);
                end
                cla(app.ax_timesignals)
                hold(app.ax_timesignals, "off")
                if app.FilterSetUp
                    yyaxis(app.ax_timesignals, "right");
                end
                app.h_rawPlt = plot(app.ax_timesignals, tRaw, xRaw);

                % init filtered data plot
                if app.FilterSetUp
                    try
                    fltPltPhase = FilterTimetable(...
                        app.FilterObjs{1,chInd,1}, ...
                        app.FilterObjs{2,chInd,1}, ...
                        rawPlt, ...
                        app.FilterObjs{4,chInd,1}, ...
                        @filter);
                    fltPltAmp = FilterTimetable(...
                        app.FilterObjs{1,chInd,2}, ...
                        app.FilterObjs{2,chInd,2}, ...
                        rawPlt, ...
                        app.FilterObjs{4,chInd,2}, ...
                        @filter);
                    tFltPhase = fltPltPhase.Time - tNow; xFltPhase = fltPltPhase.Variables;
                    tFltAmp   = fltPltAmp.Time   - tNow; xFltAmp   = fltPltAmp.Variables;
                    if ~app.check_advanced.Value
                        % do not track time exactly 
                        tFltPhase = (((-length(tFltPhase)+1):0)/fSample);
                        tFltAmp   = (((-length(tFltAmp)+1):0)/fSample);
                        tFltPhase = tFltPhase(1:(end-filtShiftPhase(chInd)+1));
                        tFltAmp   = tFltAmp(1:(end-filtShiftAmp(chInd)+1));
                    else
                        tFltPhase = seconds(tFltPhase) - filtShiftPhase(chInd)/fSample;
                        tFltAmp   = seconds(tFltAmp)   - filtShiftAmp(chInd)/fSample;
                        tFltPhase = tFltPhase(filtShiftPhase(chInd):end);
                        tFltAmp   = tFltAmp(filtShiftAmp(chInd):end);
                    end
                    xFltPhase = xFltPhase(filtShiftPhase(chInd):end);
                    xFltAmp   = xFltAmp(filtShiftAmp(chInd):end);
                    hold(app.ax_timesignals, "on")
                    yyaxis(app.ax_timesignals, "left");
                    app.h_fltPltPhase = plot(app.ax_timesignals, tFltPhase, xFltPhase);
                    hold(app.ax_timesignals, "on")
                    app.h_fltPltAmp   = plot(app.ax_timesignals, tFltAmp,   xFltAmp);

                    % init PAC plot
                    try
                    lenPACsig = min(length(tFltPhase), length(tFltAmp));
                    [~,amp,phi] = ...
                        calcPAC(xFltPhase(1:lenPACsig), xFltAmp(1:lenPACsig), 18);
                    app.h_PACplt = polarplot(app.ax_polar, [phi phi(1)], [amp amp(1)], 'LineWidth',2);
                    %{
                    app.h_PACplt = bar(app.ax_PAC, phi, amp);
                    xlabel(app.ax_PAC, 'Phase (radians)')
                    ylabel(app.ax_PAC, 'Norm. Amp.')
                    grid(app.ax_PAC, "on");
                    %}
                    catch ME4
                        app.FilterSetUp = false;
                        getReport(ME4)
                        uialert(app.UIFigure, ME4.message, 'issue starting PAC plot')
                    end

                    catch ME3
                        app.FilterSetUp = false;
                        getReport(ME3)
                        uialert(app.UIFigure, ME3.message, 'issue plotting filtered data')
                    end
                end

                % init spectrum plot
                [p,f] = periodogram(xRaw, [], [], fSample, 'power');
                hold(app.ax_spectrum, "off")
                app.h_spectrum = semilogy(app.ax_spectrum, f, p);
                app.ax_spectrum.YLabel.String = 'Power';
                app.ax_spectrum.XLabel.String = 'frequency (Hz)';

                % init spectrogram plot
                [S,ff,tSpec] = spectrogram(xRaw, [], [], f, fSample);
                tSpec = tSpec + tRaw(1);
                axes(app.ax_spectrogram)
                hold(app.ax_spectrogram, "off")
                app.h_spectrogram = imagesc(app.ax_spectrogram, tSpec, ff, abs(S));
                colorbar(app.ax_spectrogram);
                app.ax_spectrogram.YLabel.String = 'frequency (Hz)';
                app.ax_spectrogram.XLabel.String = 'time (seconds)';
                app.ax_spectrogram.Subtitle.String = 'magnitude';

                %{
                % align time axes
                xDiffLen = ...
                    (app.ax_timesignals.InnerPosition(3) - app.ax_timesignals.InnerPosition(1)) / ...
                    (app.ax_spectrogram.InnerPosition(3) - app.ax_spectrogram.InnerPosition(1));
                xDiffSt = app.ax_spectrogram.InnerPosition(1) - app.ax_timesignals.InnerPosition(1);
                dXLim = app.ax_timesignals.XLim; dXLim = diff(dXLim)*[0, xDiffLen];
                %}

                catch ME2
                    app.SignalSetUp = false;
                    getReport(ME2)
                    uialert(app.UIFigure, ME2.message, 'issue plotting data')
                end
            end

        end
        
        function StopMainLoop(app, obj, evt)
            % =====================================================
            % ================ TIMER STOP FUNCTION ================
            % =====================================================
        end

        function TimerError(app, obj, evt)
            keyboard
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % setup timer 
            app.myTimer = timer(...
                'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly
                'Period', 0.01, ...
                'TimerFcn', @app.updateDisplay, ... % callback function.  Pass the figure handle
                'StartFcn', @app.StartMainLoop, ... % callback to execute when timer starts
                ...'StopFcn',  @app.StopMainLoop, ...
                'ErrorFcn', @app.TimerError, ...
                'BusyMode', 'error');

            % setup hardware 
            RecOpts = {'Blackrock NSP'; 'Alpha Omega AlphaRS'}; % TO DO: add Neuro Omega ?
            RecSel = listdlg("PromptString", "Recording Hardware Configuration:", ...
                "ListString",RecOpts, "SelectionMode","single");
            if RecSel == 1
                % Blackrock NSP
                app.HardwareFuncs = struct(...
                    'SetupRecording', @connect_cbmex, ...
                    'ShutdownRecording', @disconnect_cbmex, ...
                    'InitRawData', @initRawData_cbmex, ...
                    'InitRecording', @InitializeRecording_cbmex, ...
                    'GetNewRawData', @getNewRawData_cbmex, ...
                    'GetTime', @getTime_cbmex);
            elseif RecSel == 2
                % AO AlphaRS; might also work with Neuro Omega (untested)
                app.HardwareFuncs = struct(...
                    'SetupRecording', @connect_AO, ...
                    'ShutdownRecording', @disconnect_AO, ...
                    'InitRawData', @initRawData_AO, ...
                    'InitRecording', @InitializeRecording_AO, ...
                    'GetNewRawData', @getNewRawData_AO, ...
                    'GetTime', @getTime_AO);
            else
                error('Recording option must be specified.')
            end

            % setup polar axes 
            app.ax_polar = polaraxes(app.Panel_ax_polar);

            app.initTic = tic;

        end

        % Button pushed function: ConnecttoNSPButton
        function ConnecttoNSPButtonPushed(app, event)
            try
            
            if app.DAQstatus
                error('DSP already connected. Please disconnect first.')
            end
            
            app.HardwareFuncs.SetupRecording();
            app.DAQstatus = true;
            app.time0 = datetime - seconds(app.HardwareFuncs.GetTime(app.initTic));
            
            % Acquire some data to get channel information. Determine which channels
            % are enabled
            [~,~,~,app.allChannelInfo] = app.HardwareFuncs.InitRawData(app.allChannelIDs, app.bufferSizeGrid);
                        
            % set channel popup menu to hold channels
            app.fSamples = cellfun(@(ch) ch.SampleRate, app.allChannelInfo);
            app.channelIDlist = cellfun(@(ch) ch.IDnumber, app.allChannelInfo);
            app.allChannelIDs = app.channelIDlist; % resets channel selection 
            app.channelList = cellfun(@(ch) [num2str(ch.IDnumber),': ',ch.Name], ...
                app.allChannelInfo, 'UniformOutput',false);
            app.ChannelSelectionDropDown.Items = [app.channelList, 'None'];
            
            % electrode grid 
            app.bufferSizeGrid = ...
                app.compGUIv2_ElectrodeGrid.inp_griddur_Value .* app.fSamples;
            app.compGUIv2_ElectrodeGrid.initGrid(app.channelList);
            
            % Set the Start/Stop toggle button to stopped state (String is 'Start' and
            % Value is 1)
            set(app.tgl_StartStop,'Text','Start', 'Value',0)
                        
            catch ME
                getReport(ME)
                uialert(app.UIFigure, ME.message, 'Connect DAQ issue')
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.HardwareFuncs.ShutdownRecording();
            app.DAQstatus = false;
        end

        % Value changed function: tgl_StartStop
        function tgl_StartStopValueChanged(app, event)
            value = app.tgl_StartStop.Value;
            % if Start
            if value == 1
                
                % Check to make sure cbmex connection is open
                if ~app.DAQstatus
                    uialert(app.UIFigure, 'No DAQ connection. Open connection before starting','Not Connected')
                    return
                end
                
                set(app.tgl_StartStop,'Text','Stop');
            
                % This starts the timer and also executes the StartFnc which grabs the
                % data, creates the buffer and plots the first bit of data
                start(app.myTimer)
                
            % Stop
            else
                set(app.tgl_StartStop,'Text','Start')
                stop(app.myTimer)
            end
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(app, event)
            value = app.LayoutDropDown.Value;
            if strcmp(value, 'ECoG')
                app.compGUIv2_ElectrodeGrid.ncol = 3;
                app.compGUIv2_ElectrodeGrid.nrow = 21;
            elseif strcmp(value, 'Depth')
                app.compGUIv2_ElectrodeGrid.ncol = 4;
                app.compGUIv2_ElectrodeGrid.nrow = 24;
            end
            app.compGUIv2_ElectrodeGrid.initGrid(app.channelList);
        end

        % Callback function: compGUIv2_ElectrodeGrid
        function compGUIv2_ElectrodeGridgridDurationChanged(app, event)
            timerWasRunning = strcmp(app.myTimer.Running, 'on');
            if timerWasRunning
                stop(app.myTimer);
            end
            app.bufferSizeGrid = ...
                app.compGUIv2_ElectrodeGrid.inp_griddur_Value .* app.fSamples;
            if timerWasRunning
                start(app.myTimer);
            end
        end

        % Callback function: compGUIv2_ElectrodeGrid
        function compGUIv2_ElectrodeGridgridRemovedChannel(app, event)
            timerWasRunning = strcmp(app.myTimer.Running, 'on');
            if timerWasRunning
                stop(app.myTimer);
            end
            app.bufferSizeGrid = ...
                app.compGUIv2_ElectrodeGrid.inp_griddur_Value .* app.fSamples;
            app.bufferSizeGrid(app.compGUIv2_ElectrodeGrid.push_remchan_Index) = 0;
            if timerWasRunning
                start(app.myTimer);
            end
        end

        % Value changed function: tgl_FilterSetUp
        function tgl_FilterSetUpValueChanged(app, event)
            value = app.tgl_FilterSetUp.Value;

            % stop running process 
            timerWasRunning = strcmp(app.myTimer.Running, 'on');
            if timerWasRunning
                stop(app.myTimer);
            end

            % set GUI flags  
            app.FilterSetUp = value;
            app.compGUIv2_ElectrodeGrid.FilterSetUp = value;
            if value
                app.compGUIv2_ElectrodeGrid.locutoff = app.PhaseLoEditField.Value;
                app.compGUIv2_ElectrodeGrid.hicutoff = app.PhaseHiEditField.Value;

                FilterObjs = cell(3, length(app.allChannelInfo), 2);
                    % dimension 1: 
                    %   filter num coeffs "b"; 
                    %   filter denom coeffs "a"; 
                    %   filter order;
                    %   filter initial conditions 
                    % dimension 2: channel 
                    % dimension 3: band (i.e. phase vs amplitude band)
                
                % filtering bound rules
                minfac         = 2;    % this many (lo)cutoff-freq cycles in filter
                min_filtorder  = 15;   % minimum filter length

                for ch = 1:width(FilterObjs)
                    srate = app.fSamples(ch);
                    nyq   = srate*0.5;  % Nyquist frequency

                    % phase filter (usually lower, e.g. beta)
                    hicutoff = app.PhaseHiEditField.Value;
                    locutoff = app.PhaseLoEditField.Value;
                    [filtwts, filtorder] = ...
                        buildFIRBPF(srate, locutoff, hicutoff, minfac, min_filtorder);
                    FilterObjs{1,ch,1} = filtwts;
                    FilterObjs{2,ch,1} = 1; % FIR filter has no poles
                    FilterObjs{3,ch,1} = filtorder;
                    FilterObjs{4,ch,1} = zeros(filtorder,1);

                    % amplitude filter (usually higher, e.g. gamma)
                    hicutoff = app.AmpHiEditField.Value;
                    locutoff = app.AmpLoEditField.Value;
                    [filtwts, filtorder] = ...
                        buildFIRBPF(srate, locutoff, hicutoff, minfac, min_filtorder);
                    FilterObjs{1,ch,2} = filtwts;
                    FilterObjs{2,ch,2} = 1; % FIR filter has no poles
                    FilterObjs{3,ch,2} = filtorder;
                    FilterObjs{4,ch,2} = zeros(filtorder,1);

                    app.FilterObjs = FilterObjs;
                end
            end

            % restart running process, if applicable 
            if timerWasRunning
                start(app.myTimer);
            end

        end

        % Value changed function: ChannelSelectionDropDown
        function ChannelSelectionDropDownValueChanged(app, event)
            value = app.ChannelSelectionDropDown.Value;
            ind = app.ChannelSelectionDropDown.ValueIndex;
            timerWasRunning = strcmp(app.myTimer.Running, 'on');
            if timerWasRunning
                stop(app.myTimer);
            end
            if strcmpi(value, 'None')
                app.SignalSetUp = false;
            else
                try
                    app.bufferSizeGrid(ind) = ...
                        app.DurationsEditField.Value * app.fSamples(ind);
                    app.selChannelInd = ind;
                    app.SignalSetUp = true;
                catch ME
                    app.SignalSetUp = false;
                    getReport(ME)
                    uialert(app.UIFigure, ME.message, 'channel selection issue')
                end
            end
            if timerWasRunning
                start(app.myTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_timesignals = uiaxes(app.UIFigure);
            title(app.ax_timesignals, 'Signal(s)')
            xlabel(app.ax_timesignals, 'time')
            ylabel(app.ax_timesignals, 'Amplitude')
            zlabel(app.ax_timesignals, 'Z')
            app.ax_spectrum = uiaxes(app.UIFigure);
            title(app.ax_spectrum, 'Spectrum')
            xlabel(app.ax_spectrum, 'frequency')
            ylabel(app.ax_spectrum, 'Magnitude')
            zlabel(app.ax_spectrum, 'Z')
            app.ax_spectrogram = uiaxes(app.UIFigure);
            title(app.ax_spectrogram, 'Spectrogram')
            xlabel(app.ax_spectrogram, 'time')
            ylabel(app.ax_spectrogram, 'frequency')
            zlabel(app.ax_spectrogram, 'Z')
            app.compGUIv2_ElectrodeGrid = compGUIv2_ElectrodeGrid(app.UIFigure);
            app.compGUIv2_ElectrodeGrid.hicutoff = NaN;
            app.compGUIv2_ElectrodeGrid.locutoff = NaN;
            app.compGUIv2_ElectrodeGrid.gridDurationChangedFcn = createCallbackFcn(app, @compGUIv2_ElectrodeGridgridDurationChanged, true);
            app.compGUIv2_ElectrodeGrid.gridRemovedChannelFcn = createCallbackFcn(app, @compGUIv2_ElectrodeGridgridRemovedChannel, true);
            app.ConnecttoNSPButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoNSPButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoNSPButtonPushed, true);
            app.ConnecttoNSPButton.Text = 'Connect to NSP';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.tgl_StartStop = uibutton(app.UIFigure, 'state');
            app.tgl_StartStop.ValueChangedFcn = createCallbackFcn(app, @tgl_StartStopValueChanged, true);
            app.tgl_StartStop.Text = '';
            app.LayoutDropDownLabel = uilabel(app.UIFigure);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.UIFigure);
            app.LayoutDropDown.Items = {'ECoG', 'Depth'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownValueChanged, true);
            app.LayoutDropDown.Value = 'ECoG';
            app.ChannelSelectionDropDownLabel = uilabel(app.UIFigure);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.UIFigure);
            app.ChannelSelectionDropDown.Items = {'None'};
            app.ChannelSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionDropDownValueChanged, true);
            app.ChannelSelectionDropDown.Value = 'None';
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Value = 3;
            app.PhaseLoEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseLoEditFieldLabel.Text = 'Phase Lo';
            app.PhaseLoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseLoEditField.Value = 13;
            app.FreqsHzLabel = uilabel(app.UIFigure);
            app.FreqsHzLabel.Text = 'Freqs (Hz)';
            app.PhaseHiEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseHiEditFieldLabel.Text = 'Phase Hi';
            app.PhaseHiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseHiEditField.Value = 30;
            app.AmpLoEditFieldLabel = uilabel(app.UIFigure);
            app.AmpLoEditFieldLabel.Text = 'Amp Lo';
            app.AmpLoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmpLoEditField.Value = 50;
            app.AmpHiEditFieldLabel = uilabel(app.UIFigure);
            app.AmpHiEditFieldLabel.Text = 'Amp Hi';
            app.AmpHiEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmpHiEditField.Value = 200;
            app.tgl_FilterSetUp = uibutton(app.UIFigure, 'state');
            app.tgl_FilterSetUp.ValueChangedFcn = createCallbackFcn(app, @tgl_FilterSetUpValueChanged, true);
            app.tgl_FilterSetUp.Text = 'Filter';
            app.check_advanced = uicheckbox(app.UIFigure);
            app.check_advanced.Text = 'Advanced Plot';
            app.Panel_ax_polar = uipanel(app.UIFigure);
            app.Panel_ax_polar.Title = 'PAC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2_ChannelSelectDetails

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end