classdef GUIv2_ElectrodeGrid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LayoutDropDown           matlab.ui.control.DropDown
        LayoutDropDownLabel      matlab.ui.control.Label
        tgl_StartStop            matlab.ui.control.StateButton
        DisconnectButton         matlab.ui.control.Button
        ConnecttoNSPButton       matlab.ui.control.Button
        compGUIv2_ElectrodeGrid  compGUIv2_ElectrodeGrid
    end

    
    properties (Access = public)
        DAQstatus logical = false; % is connected to data source?
        FilterSetUp logical = false; % no filtering in this version of the app
        allChannelIDs = []; % ID number of channels to display 
        channelIDlist = []; % list of all available channel IDs
        allChannelInfo = {}; % info data structure 
        channelList = {}; % channel names for on-screen display
        rawData = {}; % raw data structure 
        bufferSizeGrid = 0; % samples 
        fSamples % sample rate(s)
    end
    
    properties (Access = private)
        HardwareFuncs = []; % structure of functions to interact with hardware 
        myTimer
    end
    
    methods (Access = private)
        
        function [newBuffer, newTail, newAll] = ...
                bufferjuggle(app, oldBuffer, oldTail, newData, bufferFunc)
            % helper function
            newBuffer = bufferFunc(oldBuffer, oldTail);
            newTail = newData;
            newAll = bufferFunc(newBuffer, newTail);
        end

        function updateDisplay(app, obj, evt)
            % =====================================================
            % ================= MAIN GUI FUNCTION =================
            % =====================================================

            try 

            % ensure connection with hardware 
            if ~app.DAQstatus
                stop(app.myTimer);
            end

            % get raw data (adapted from iterReadBrain) 
            rawIDs = app.allChannelIDs;
            rawData = app.rawData; chInfo = rawData(1,:);
            [newTails, tailNames, tailIDs] = ...
                app.HardwareFuncs.GetNewRawData(rawIDs);
            if width(rawData) == width(newTails)
                % assume same order 
                for CH = 1:width(rawData)
                    newTail = newTails{CH};
                    [rawData{2,CH}, rawData{3,CH}, rawData{4,CH}] = ...
                        bufferjuggle(app,rawData{2,CH},rawData{3,CH},newTail,@bufferData);
                end
            else
                % assign manually using channel ID
                for ch = 1:size(newTails,2)
                    newTail = newTails{ch};
                    tailName = tailNames{ch};
                    %CH = find(strcmp(tailName, rawNames)); 
                    tailID = tailIDs(ch);
                    CH = find(tailID == rawIDs);
                    if isempty(CH)
                        error(['Unrecognized new raw data label: ',tailName]);
                    end
                    if length(CH) > 1
                        error(['Raw data label ',tailName,' is not unique.']);
                    end
                    [rawData{2,CH}, rawData{3,CH}, rawData{4,CH}] = ...
                        bufferjuggle(app,rawData{2,CH},rawData{3,CH},newTail,@bufferData);
                end
            end
            app.rawData = rawData; 

            % update electrode grid 
            if app.compGUIv2_ElectrodeGrid.showElecGrid
                try
                    app.compGUIv2_ElectrodeGrid.updateGrid(...
                        app.channelIDlist, rawIDs, rawData(4,:), ...
                        app.bufferSizeGrid, app.fSamples);
                catch ME4
                    getReport(ME4)
                    uialert(app.UIFigure, ME4.message, 'Electrode Grid Issue')
                    app.compGUIv2_ElectrodeGrid.showElecGrid = false;
                end
            end

            catch ME
                if contains(ME.message, 'No continuous data')
                    % do nothing; wait for data
                else
                    getReport(ME)
                    stop(app.myTimer)
                    keyboard
                end
            end
        end
        
        function StartMainLoop(app, obj, evt)
            % ====================================================
            % =============== TIMER START FUNCTION ===============
            % ====================================================
            % (re-)init data structs
            app.HardwareFuncs.ShutdownRecording();
            try
                [rawH, rawT, rawB, rawN] = app.HardwareFuncs.InitRawData(...
                    app.allChannelIDs, app.bufferSizeGrid);
            catch ME1 
                warning(['Error on first attempt: ',ME1.message]);
                pause(1);
                [rawH, rawT, rawB, rawN] = app.HardwareFuncs.InitRawData(...
                    app.allChannelIDs, app.bufferSizeGrid);
            end
            app.rawData = [rawN; rawH; rawT; rawB];
        end
        
        function StopMainLoop(app, obj, evt)
            % =====================================================
            % ================ TIMER STOP FUNCTION ================
            % =====================================================
        end

        function TimerError(app, obj, evt)
            keyboard
        end
        
    end
    
    methods (Access = public)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % setup timer 
            app.myTimer = timer(...
                'ExecutionMode', 'fixedSpacing', ...       % Run timer repeatedly
                'Period', 0.01, ...
                'TimerFcn', @app.updateDisplay, ... % callback function.  Pass the figure handle
                'StartFcn', @app.StartMainLoop, ... % callback to execute when timer starts
                ...'StopFcn',  @app.StopMainLoop, ...
                'ErrorFcn', @app.TimerError, ...
                'BusyMode', 'error');

            % setup hardware 
            RecOpts = {'Blackrock NSP'; 'Alpha Omega AlphaRS'}; % TO DO: add Neuro Omega ?
            RecSel = listdlg("PromptString", "Recording Hardware Configuration:", ...
                "ListString",RecOpts, "SelectionMode","single");
            if RecSel == 1
                % Blackrock NSP
                app.HardwareFuncs = struct(...
                    'SetupRecording', @connect_cbmex, ...
                    'ShutdownRecording', @disconnect_cbmex, ...
                    'InitRawData', @initRawData_cbmex, ...
                    'InitRecording', @InitializeRecording_cbmex, ...
                    'GetNewRawData', @getNewRawData_cbmex, ...
                    'GetTime', @getTime_cbmex);
            elseif RecSel == 2
                % AO AlphaRS; might also work with Neuro Omega (untested)
                app.HardwareFuncs = struct(...
                    'SetupRecording', @connect_AO, ...
                    'ShutdownRecording', @disconnect_AO, ...
                    'InitRawData', @initRawData_AO, ...
                    'InitRecording', @InitializeRecording_AO, ...
                    'GetNewRawData', @getNewRawData_AO, ...
                    'GetTime', @getTime_AO);
            else
                error('Recording option must be specified.')
            end
            
        end

        % Button pushed function: ConnecttoNSPButton
        function ConnecttoNSPButtonPushed(app, event)
            try
            
            if app.DAQstatus
                error('DSP already connected. Please disconnect first.')
            end
            
            app.HardwareFuncs.SetupRecording();
            app.DAQstatus = true;
            
            % Acquire some data to get channel information. Determine which channels
            % are enabled
            [~,~,~,app.allChannelInfo] = app.HardwareFuncs.InitRawData(app.allChannelIDs, app.bufferSizeGrid);
                        
            % set channel popup menu to hold channels
            app.fSamples = cellfun(@(ch) ch.SampleRate, app.allChannelInfo);
            app.channelIDlist = cellfun(@(ch) ch.IDnumber, app.allChannelInfo);
            app.allChannelIDs = app.channelIDlist; % resets channel selection 
            app.channelList = cellfun(@(ch) [num2str(ch.IDnumber),': ',ch.Name], ...
                app.allChannelInfo, 'UniformOutput',false);
            
            % electrode grid 
            app.bufferSizeGrid = ...
                app.compGUIv2_ElectrodeGrid.inp_griddur_Value .* app.fSamples;
            app.compGUIv2_ElectrodeGrid.initGrid(app.channelList);
            
            % Set the Start/Stop toggle button to stopped state (String is 'Start' and
            % Value is 1)
            set(app.tgl_StartStop,'Text','Start', 'Value',0)
                        
            catch ME
                getReport(ME)
                uialert(app.UIFigure, ME.message, 'Connect DAQ issue')
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.HardwareFuncs.ShutdownRecording();
            app.DAQstatus = false;
        end

        % Value changed function: tgl_StartStop
        function tgl_StartStopValueChanged(app, event)
            value = app.tgl_StartStop.Value;
            % if Start
            if value == 1
                
                % Check to make sure cbmex connection is open
                if ~app.DAQstatus
                    uialert(app.UIFigure, 'No DAQ connection. Open connection before starting','Not Connected')
                    return
                end
                
                set(app.tgl_StartStop,'Text','Stop');
            
                % This starts the timer and also executes the StartFnc which grabs the
                % data, creates the buffer and plots the first bit of data
                start(app.myTimer)
                
            % Stop
            else
                set(app.tgl_StartStop,'Text','Start')
                stop(app.myTimer)
            end
        end

        % Callback function: compGUIv2_ElectrodeGrid
        function compGUIv2_ElectrodeGridgridDurationChanged(app, event)
            timerWasRunning = strcmp(app.myTimer.Running, 'on');
            if timerWasRunning
                stop(app.myTimer);
            end
            app.bufferSizeGrid = ...
                app.compGUIv2_ElectrodeGrid.inp_griddur_Value .* app.fSamples;
            if timerWasRunning
                start(app.myTimer);
            end
        end

        % Value changed function: LayoutDropDown
        function LayoutDropDownValueChanged(app, event)
            value = app.LayoutDropDown.Value;
            if strcmp(value, 'ECoG')
                app.compGUIv2_ElectrodeGrid.ncol = 3;
                app.compGUIv2_ElectrodeGrid.nrow = 21;
            elseif strcmp(value, 'Depth')
                app.compGUIv2_ElectrodeGrid.ncol = 4;
                app.compGUIv2_ElectrodeGrid.nrow = 24;
            end
            app.compGUIv2_ElectrodeGrid.initGrid(app.channelList);
        end

        % Callback function: compGUIv2_ElectrodeGrid
        function compGUIv2_ElectrodeGridgridRemovedChannel(app, event)
            timerWasRunning = strcmp(app.myTimer.Running, 'on');
            if timerWasRunning
                stop(app.myTimer);
            end
            app.bufferSizeGrid = ...
                app.compGUIv2_ElectrodeGrid.inp_griddur_Value .* app.fSamples;
            app.bufferSizeGrid(app.compGUIv2_ElectrodeGrid.push_remchan_Index) = 0;
            if timerWasRunning
                start(app.myTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.compGUIv2_ElectrodeGrid = compGUIv2_ElectrodeGrid(app.UIFigure);
            app.compGUIv2_ElectrodeGrid.hicutoff = NaN;
            app.compGUIv2_ElectrodeGrid.locutoff = NaN;
            app.compGUIv2_ElectrodeGrid.gridDurationChangedFcn = createCallbackFcn(app, @compGUIv2_ElectrodeGridgridDurationChanged, true);
            app.compGUIv2_ElectrodeGrid.gridRemovedChannelFcn = createCallbackFcn(app, @compGUIv2_ElectrodeGridgridRemovedChannel, true);
            app.ConnecttoNSPButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoNSPButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoNSPButtonPushed, true);
            app.ConnecttoNSPButton.Text = 'Connect to NSP';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.tgl_StartStop = uibutton(app.UIFigure, 'state');
            app.tgl_StartStop.ValueChangedFcn = createCallbackFcn(app, @tgl_StartStopValueChanged, true);
            app.tgl_StartStop.Text = '';
            app.LayoutDropDownLabel = uilabel(app.UIFigure);
            app.LayoutDropDownLabel.Text = 'Layout';
            app.LayoutDropDown = uidropdown(app.UIFigure);
            app.LayoutDropDown.Items = {'ECoG', 'Depth'};
            app.LayoutDropDown.ValueChangedFcn = createCallbackFcn(app, @LayoutDropDownValueChanged, true);
            app.LayoutDropDown.Value = 'ECoG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIv2_ElectrodeGrid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end