classdef compGUIv2_ElectrodeGrid < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        push_remchan             matlab.ui.control.Button
        inp_gridmax              matlab.ui.control.NumericEditField
        MaxEditFieldLabel        matlab.ui.control.Label
        inp_gridmin              matlab.ui.control.NumericEditField
        MinEditFieldLabel        matlab.ui.control.Label
        inp_griddur              matlab.ui.control.NumericEditField
        DurationsEditFieldLabel  matlab.ui.control.Label
        pop_elecgrid             matlab.ui.control.DropDown
        ShowDropDownLabel        matlab.ui.control.Label
        ax_elecgrid              matlab.ui.control.UIAxes
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        gridDurationChanged % duration of electrode grid has been changed
        gridRemovedChannel % channel requested to be removed from grid display
    end

    properties (Access = private)
        channelStrikeout = []; % handle to plot of disabled channel indicators
        gridChannelList = {}; % cell array of channel names that appear in the grid
        gridChannelX = []; % horizontal coordinates corresponding to channel list
        gridChannelY = []; % vertical coordinate corresponding to channel list
    end
    
    properties (Access = public)
        FilterSetUp logical = false; % flag that determines if filter has been set up
        elecGridFunc = @(~,~) nan; % function takes in data and sample rate and outputs grid value
        elecGridFunc2 = @(~,~,~) nan; % takes in sample rate and two datas and outputs grid value
        elecGridImg = []; % imagesc handle for the grid
        hicutoff double = nan; % filter high cutoff frequency
        inp_griddur_Value double = 1; % value property of inp_griddur
        locutoff double = nan; % low cutoff frequency of filter
        ncol double = 3; % # of columns
        nrow double = 21; % # of rows
        push_remchan_Index = []; % index(es) of channels to be excluded from grid
        showElecGrid logical = false; % enable/disable this component
        useGridFunc2 logical = false; % whether to use Func2 versus Func
    end
    
    
    methods (Access = public)
        
        function initGrid(comp, channelList)
            % should be called on connection with hardware 
            gridmaxval = comp.inp_gridmax.Value;
            gridminval = comp.inp_gridmin.Value;
            axes(comp.ax_elecgrid)
            [comp.elecGridImg, comp.gridChannelList, comp.gridChannelX, comp.gridChannelY] = ...
                helperGUIv1_ElectrodeGridInit(comp.ax_elecgrid, channelList, ...
                comp.nrow, comp.ncol, gridminval, gridmaxval);
        end
        
        function updateGrid(comp, channelIDlist, rawIDs, dataBuffs, ...
                bufferSizeGrid, fSamples)
            % should be called in main loop whenever there is new data 
            if comp.useGridFunc2
                % interpret dataBuffs as filtD4, which has two rows for
                % each filtered data set 
                comp.elecGridImg.CData = helperGUIv2_ElectrodeGridUpdatePAC(...
                    comp.elecGridImg, comp.elecGridFunc2, ...
                    channelIDlist, rawIDs, dataBuffs, bufferSizeGrid, fSamples);
            else
                % interpret dataBuffs as rawD4
                comp.elecGridImg.CData = helperGUIv1_ElectrodeGridUpdate(...
                    comp.elecGridImg, comp.elecGridFunc, ...
                    channelIDlist, rawIDs, dataBuffs, bufferSizeGrid, fSamples);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: pop_elecgrid
        function pop_elecgridValueChanged(comp, event)
            % changes what calculation is displayed as grid values 
            sel = comp.pop_elecgrid.Value;
            if strcmpi(sel, 'PAC')
                comp.elecGridFunc2 = @(phi,amp,~) calcPAC(phi,amp);
                comp.useGridFunc2 = true;
                comp.showElecGrid = true;
            else
            [comp.showElecGrid, comp.elecGridFunc] = helperGUIv1_ElectrodeGridFuncSelect(...
                sel, comp.FilterSetUp, comp.locutoff, comp.hicutoff);
            comp.useGridFunc2 = false;
            end
        end

        % Value changed function: inp_gridmin
        function inp_gridminValueChanged(comp, event)
            value = comp.inp_gridmin.Value;
            comp.ax_elecgrid.CLim(1) = value;
        end

        % Value changed function: inp_gridmax
        function inp_gridmaxValueChanged(comp, event)
            value = comp.inp_gridmax.Value;
            comp.ax_elecgrid.CLim(2) = value;
        end

        % Value changed function: inp_griddur
        function inp_griddurValueChanged(comp, event)
            comp.inp_griddur_Value = comp.inp_griddur.Value;
            notify(comp, 'gridDurationChanged');
        end

        % Button pushed function: push_remchan
        function push_remchanButtonPushed(comp, event)
            [remind, ok] = listdlg("ListString",comp.gridChannelList, ...
                "PromptString", 'REMOVE these channels:');
            if ok
                comp.push_remchan_Index = remind;
                notify(comp, 'gridRemovedChannel');
                delete(comp.channelStrikeout);
                comp.channelStrikeout = plot(comp.ax_elecgrid, ...
                    comp.gridChannelX(remind), comp.gridChannelY(remind), ...
                    'xw', 'MarkerSize',10, 'LineWidth',2);
            end
        end

        % Button down function: ax_elecgrid
        function ax_elecgridButtonDown(comp, event)
            %comp
            %event
            %event.IntersectionPoint --> [horizontal, vertical, -1] mouseclick
            %keyboard
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 450 720];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ax_elecgrid = uiaxes(comp);
            comp.ax_elecgrid.XTick = [];
            comp.ax_elecgrid.YTick = [];
            comp.ax_elecgrid.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @ax_elecgridButtonDown, true);
            comp.ShowDropDownLabel = uilabel(comp);
            comp.ShowDropDownLabel.Text = 'Show:';
            comp.pop_elecgrid = uidropdown(comp);
            comp.pop_elecgrid.Items = {'None', 'Selected Band Power', 'Beta Power', 'Theta Power', 'Gamma Power', 'PAC'};
            comp.pop_elecgrid.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @pop_elecgridValueChanged, true);
            comp.pop_elecgrid.Value = 'None';
            comp.DurationsEditFieldLabel = uilabel(comp);
            comp.DurationsEditFieldLabel.Text = 'Duration (s)';
            comp.inp_griddur = uieditfield(comp, 'numeric');
            comp.inp_griddur.Limits = [0 Inf];
            comp.inp_griddur.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inp_griddurValueChanged, true);
            comp.inp_griddur.Value = 1;
            comp.MinEditFieldLabel = uilabel(comp);
            comp.MinEditFieldLabel.Text = 'Min';
            comp.inp_gridmin = uieditfield(comp, 'numeric');
            comp.inp_gridmin.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inp_gridminValueChanged, true);
            comp.MaxEditFieldLabel = uilabel(comp);
            comp.MaxEditFieldLabel.Text = 'Max';
            comp.inp_gridmax = uieditfield(comp, 'numeric');
            comp.inp_gridmax.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @inp_gridmaxValueChanged, true);
            comp.inp_gridmax.Value = 1000000;
            comp.push_remchan = uibutton(comp, 'push');
            comp.push_remchan.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @push_remchanButtonPushed, true);
            comp.push_remchan.Text = 'Remove Channels';
        end
    end
end